classdef sEMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ReadfileButton                  matlab.ui.control.Button
        SettingsPanel                   matlab.ui.container.Panel
        PlottheButtonGroup              matlab.ui.container.ButtonGroup
        normalizeddataButton            matlab.ui.control.RadioButton
        movingrmsofthedataButton        matlab.ui.control.RadioButton
        dataSavitzkyGolayfilteredButton  matlab.ui.control.RadioButton
        databandpassfilteredButton      matlab.ui.control.RadioButton
        datawithoutoutliersButton       matlab.ui.control.RadioButton
        fouriertransformButton          matlab.ui.control.RadioButton
        datainmVButton                  matlab.ui.control.RadioButton
        DataofButtonGroup               matlab.ui.container.ButtonGroup
        allmusclesButton                matlab.ui.control.RadioButton
        selectedmuscleButton            matlab.ui.control.RadioButton
        ChoosemuscleButtonGroup         matlab.ui.container.ButtonGroup
        trapezius_rightButton           matlab.ui.control.RadioButton
        trapezius_leftButton            matlab.ui.control.RadioButton
        delt_rightButton                matlab.ui.control.RadioButton
        delt_leftButton                 matlab.ui.control.RadioButton
        ECR_rightButton                 matlab.ui.control.RadioButton
        RightPanel                      matlab.ui.container.Panel
        BandpassfiltersettingsPanel     matlab.ui.container.Panel
        HighcutofffrequencySlider       matlab.ui.control.Slider
        HighcutofffrequencySliderLabel  matlab.ui.control.Label
        LowcutofffrequencySlider        matlab.ui.control.Slider
        LowcutofffrequencySliderLabel   matlab.ui.control.Label
        SavitzkyGolayfiltersettingsPanel  matlab.ui.container.Panel
        OrderSlider                     matlab.ui.control.Slider
        OrderSliderLabel                matlab.ui.control.Label
        FramelengthSlider               matlab.ui.control.Slider
        FramelengthSliderLabel          matlab.ui.control.Label
        MovingRMSsettingsPanel          matlab.ui.container.Panel
        OverlapSlider                   matlab.ui.control.Slider
        OverlapSliderLabel              matlab.ui.control.Label
        WindowlengthSlider              matlab.ui.control.Slider
        WindowlengthSliderLabel         matlab.ui.control.Label
        OutlierremovalsettingsPanel     matlab.ui.container.Panel
        DeviationnumberSlider           matlab.ui.control.Slider
        DeviationnumberSliderLabel      matlab.ui.control.Label
        WindowsizeSlider                matlab.ui.control.Slider
        WindowsizeSliderLabel           matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        DonePanel                       matlab.ui.container.Panel
        DoneButton                      matlab.ui.control.Button
        PlotPanel_7                     matlab.ui.container.Panel
        UIAxes7                         matlab.ui.control.UIAxes
        PlotPanel_6                     matlab.ui.container.Panel
        UIAxes6                         matlab.ui.control.UIAxes
        PlotPanel_5                     matlab.ui.container.Panel
        UIAxes5                         matlab.ui.control.UIAxes
        PlotPanel_4                     matlab.ui.container.Panel
        UIAxes4                         matlab.ui.control.UIAxes
        PlotPanel_3                     matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        PlotPanel_2                     matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        PlotPanel                       matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        sEMGLabel                       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        path = ''
        file = ''
        header = []
        data = [0,0,0,0,0]
        M_mV = [0,0,0,0,0]
        samplingRate
        fourier = [0,0,0,0,0]
        no_outliers = [0,0,0,0,0]
        filtered = [0,0,0,0,0]
        sgf = [0,0,0,0,0]
        rms = [0,0,0,0,0]
        movrmsExp = 0
        windowLength = 20
        NormalizedData = [0,0,0,0,0]
        overlap = 19
        deviationNumber = 2
        windowSize = 20
        lowCutoffFreq = 100
        highCutoffFreq = 300
        order = 2
        frameLength = 15
        f
    end
    methods (Access = public)               
        function plotFunction(app, column)
            legend(app.UIAxes, 'off');
            legend(app.UIAxes2, 'off');
            legend(app.UIAxes3, 'off');
            legend(app.UIAxes4, 'off');
            legend(app.UIAxes5, 'off');
            legend(app.UIAxes6, 'off');
            legend(app.UIAxes7, 'off');
            
            plot(app.UIAxes, (1:length(app.M_mV(:,column)))', app.M_mV(:,column))
            xlim(app.UIAxes, [0, length(app.M_mV(:,column))]);
            
%             plot(app.UIAxes2, (1:length(app.fourier(:,column)))', app.fourier(:,column))
%             xlim(app.UIAxes2, [0, length(app.fourier(:,column))]);

            plot(app.UIAxes2, (app.f), app.fourier(:,column))
            xlim(app.UIAxes2, [0, app.f(end)]);
            
            plot(app.UIAxes3, (1:length(app.no_outliers(:,column)))', app.no_outliers(:,column))
            xlim(app.UIAxes3, [0, length(app.no_outliers(:,column))]);
            
            plot(app.UIAxes4, (1:length(app.filtered(:,column)))', app.filtered(:,column))
            xlim(app.UIAxes4, [0, length(app.filtered(:,column))]);
            
            plot(app.UIAxes5, (1:length(app.sgf(:,column)))', app.sgf(:,column))
            xlim(app.UIAxes5, [0, length(app.sgf(:,column))]);
            
            if (app.movrmsExp ~= 0)
                y = abs(app.M_mV(:,column));
                app.rms = app.movrmsExp(y)';
                plot(app.UIAxes6, (1:length(app.rms(app.windowLength:end)))', app.rms(app.windowLength:end))
                xlim(app.UIAxes6, [0, length(app.rms(app.windowLength:end))]);
            end    

            plot(app.UIAxes7, (1:length(app.NormalizedData(:,column)))', app.NormalizedData(:,column))
            xlim(app.UIAxes7, [0, length(app.NormalizedData(:,column))]);
            
        end
        
        function plotAll(app)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            cla(app.UIAxes7);

            hold(app.UIAxes, 'on');
            plot(app.UIAxes, (1:length(app.M_mV(:,1)))', app.M_mV(:,1))
            xlim(app.UIAxes, [0, length(app.M_mV(:,1))]);
            plot(app.UIAxes, (1:length(app.M_mV(:,2)))', app.M_mV(:,2))
            xlim(app.UIAxes, [0, length(app.M_mV(:,2))]);
            plot(app.UIAxes, (1:length(app.M_mV(:,3)))', app.M_mV(:,3))
            xlim(app.UIAxes, [0, length(app.M_mV(:,3))]);
            plot(app.UIAxes, (1:length(app.M_mV(:,4)))', app.M_mV(:,4))
            xlim(app.UIAxes, [0, length(app.M_mV(:,4))]);
            plot(app.UIAxes, (1:length(app.M_mV(:,5)))', app.M_mV(:,5))
            legend(app.UIAxes, {'channel1', 'channel2', 'channel3', 'channel4', 'channel5'}, 'Location', 'Best');
            xlim(app.UIAxes, [0, length(app.M_mV(:,5))]);
            hold(app.UIAxes, 'off');
            
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, (1:length(app.fourier(:,1)))', app.fourier(:,1))
            xlim(app.UIAxes2, [0, length(app.fourier(:,1))]);
            plot(app.UIAxes2, (1:length(app.fourier(:,2)))', app.fourier(:,2))
            xlim(app.UIAxes2, [0, length(app.fourier(:,2))]);
            plot(app.UIAxes2, (1:length(app.fourier(:,3)))', app.fourier(:,3))
            xlim(app.UIAxes2, [0, length(app.fourier(:,3))]);
            plot(app.UIAxes2, (1:length(app.fourier(:,4)))', app.fourier(:,4))
            xlim(app.UIAxes2, [0, length(app.fourier(:,4))]);
            plot(app.UIAxes2, (1:length(app.fourier(:,5)))', app.fourier(:,5))
            legend(app.UIAxes2, {'channel1', 'channel2', 'channel3', 'channel4', 'channel5'}, 'Location', 'Best');
            xlim(app.UIAxes2, [0, length(app.fourier(:,5))]);
            hold(app.UIAxes2, 'off');
            
            hold(app.UIAxes3, 'on');
            plot(app.UIAxes3, (1:length(app.no_outliers(:,1)))', app.no_outliers(:,1))
            xlim(app.UIAxes3, [0, length(app.no_outliers(:,1))]);
            plot(app.UIAxes3, (1:length(app.no_outliers(:,2)))', app.no_outliers(:,2))
            xlim(app.UIAxes3, [0, length(app.no_outliers(:,2))]);
            plot(app.UIAxes3, (1:length(app.no_outliers(:,3)))', app.no_outliers(:,3))
            xlim(app.UIAxes3, [0, length(app.no_outliers(:,3))]);
            plot(app.UIAxes3, (1:length(app.no_outliers(:,4)))', app.no_outliers(:,4))
            xlim(app.UIAxes3, [0, length(app.no_outliers(:,4))]);
            plot(app.UIAxes3, (1:length(app.no_outliers(:,5)))', app.no_outliers(:,5))
            legend(app.UIAxes3, {'channel1', 'channel2', 'channel3', 'channel4', 'channel5'}, 'Location', 'Best');
            xlim(app.UIAxes3, [0, length(app.no_outliers(:,5))]);
            hold(app.UIAxes3, 'off');
            
            hold(app.UIAxes4, 'on');
            plot(app.UIAxes4, (1:length(app.filtered(:,1)))', app.filtered(:,1))
            xlim(app.UIAxes4, [0, length(app.filtered(:,1))]);
            plot(app.UIAxes4, (1:length(app.filtered(:,2)))', app.filtered(:,2))
            xlim(app.UIAxes4, [0, length(app.filtered(:,2))]);
            plot(app.UIAxes4, (1:length(app.filtered(:,3)))', app.filtered(:,3))
            xlim(app.UIAxes4, [0, length(app.filtered(:,3))]);
            plot(app.UIAxes4, (1:length(app.filtered(:,4)))', app.filtered(:,4))
            xlim(app.UIAxes4, [0, length(app.filtered(:,4))]);
            plot(app.UIAxes4, (1:length(app.filtered(:,5)))', app.filtered(:,5))
            legend(app.UIAxes4, {'channel1', 'channel2', 'channel3', 'channel4', 'channel5'}, 'Location', 'Best');
            xlim(app.UIAxes4, [0, length(app.filtered(:,5))]);
            hold(app.UIAxes4, 'off');
            
            hold(app.UIAxes5, 'on');
            plot(app.UIAxes5, (1:length(app.sgf(:,1)))', app.sgf(:,1))
            xlim(app.UIAxes5, [0, length(app.sgf(:,1))]);
            plot(app.UIAxes5, (1:length(app.sgf(:,2)))', app.sgf(:,2))
            xlim(app.UIAxes5, [0, length(app.sgf(:,2))]);
            plot(app.UIAxes5, (1:length(app.sgf(:,3)))', app.sgf(:,3))
            xlim(app.UIAxes5, [0, length(app.sgf(:,3))]);
            plot(app.UIAxes5, (1:length(app.sgf(:,4)))', app.sgf(:,4))
            xlim(app.UIAxes5, [0, length(app.sgf(:,4))]);
            plot(app.UIAxes5, (1:length(app.sgf(:,5)))', app.sgf(:,5))
            legend(app.UIAxes5, {'channel1', 'channel2', 'channel3', 'channel4', 'channel5'}, 'Location', 'Best');
            xlim(app.UIAxes5, [0, length(app.sgf(:,5))]);
            hold(app.UIAxes5, 'off');
            
            if (app.movrmsExp ~= 0)
                hold(app.UIAxes6, 'on');
                y = abs(app.M_mV(:,1));
                app.rms = app.movrmsExp(y)';
                plot(app.UIAxes6, (1:length(app.rms(app.windowLength:end)))', app.rms(app.windowLength:end))
                xlim(app.UIAxes6, [0, length(app.rms(app.windowLength:end))]);
                y = abs(app.M_mV(:,2));
                app.rms = app.movrmsExp(y)';
                plot(app.UIAxes6, (1:length(app.rms(app.windowLength:end)))', app.rms(app.windowLength:end))
                xlim(app.UIAxes6, [0, length(app.rms(app.windowLength:end))]);
                y = abs(app.M_mV(:,3));
                app.rms = app.movrmsExp(y)';
                plot(app.UIAxes6, (1:length(app.rms(app.windowLength:end)))', app.rms(app.windowLength:end))
                xlim(app.UIAxes6, [0, length(app.rms(app.windowLength:end))]);
                y = abs(app.M_mV(:,4));
                app.rms = app.movrmsExp(y)';
                plot(app.UIAxes6, (1:length(app.rms(app.windowLength:end)))', app.rms(app.windowLength:end))
                xlim(app.UIAxes6, [0, length(app.rms(app.windowLength:end))]);
                y = abs(app.M_mV(:,5));
                app.rms = app.movrmsExp(y)';
                plot(app.UIAxes6, (1:length(app.rms(app.windowLength:end)))', app.rms(app.windowLength:end))
                legend(app.UIAxes6, {'channel1', 'channel2', 'channel3', 'channel4', 'channel5'}, 'Location', 'Best');
                xlim(app.UIAxes6, [0, length(app.rms(app.windowLength:end))]);
                hold(app.UIAxes6, 'off');
            end
            
            hold(app.UIAxes7, 'on');
            plot(app.UIAxes7, (1:length(app.NormalizedData(:,1)))', app.NormalizedData(:,1))
            xlim(app.UIAxes7, [0, length(app.NormalizedData(:,1))]);
            plot(app.UIAxes7, (1:length(app.NormalizedData(:,2)))', app.NormalizedData(:,2))
            xlim(app.UIAxes7, [0, length(app.NormalizedData(:,2))]);
            plot(app.UIAxes7, (1:length(app.NormalizedData(:,3)))', app.NormalizedData(:,3))
            xlim(app.UIAxes7, [0, length(app.NormalizedData(:,3))]);
            plot(app.UIAxes7, (1:length(app.NormalizedData(:,4)))', app.NormalizedData(:,4))
            xlim(app.UIAxes7, [0, length(app.NormalizedData(:,4))]);
            plot(app.UIAxes7, (1:length(app.NormalizedData(:,5)))', app.NormalizedData(:,5))
            legend(app.UIAxes7, {'channel1', 'channel2', 'channel3', 'channel4', 'channel5'}, 'Location', 'Best');
            xlim(app.UIAxes7, [0, length(app.NormalizedData(:,5))]);     
            hold(app.UIAxes7, 'off');
        end
        
        function update(app)
            %% Transform raw data to mV
            app.M_mV = transformTo_mV(app.data, app.header.resolution);
            
            %% FFT analysis   
            fourierall = fft(app.M_mV,[],1);
            L=length(app.data);
            P2 = abs(fourierall/L);
            P1 = P2(1:L/2+1,:);
            app.f = app.header.samplingRate*(0:(L/2))/L;
            app.fourier = P1;
            % app.fourier = abs(fourierall(1:(length(fourierall)/2),:));
            % filter peak value
            app.fourier(1,:)=0;
            
            %% Outlier removal
            app.no_outliers = FilterOutlierWindow(app.M_mV,app.deviationNumber,app.windowSize);
            
            %%% Processing track A
            % rectify data
            app.no_outliers = abs(app.no_outliers);
            
            %% Bandpassfilter
            % cutoffrequency
            app.filtered = BandpassFilter(app.header,app.no_outliers,app.lowCutoffFreq,app.highCutoffFreq,app.samplingRate);

            %% Savitzky-Golay filtering
            app.sgf = Savitzky_GolayFilter(app.no_outliers,app.order,app.frameLength);
            
            %%% Processing track B
            %% Moving RMS
            app.movrmsExp = dsp.MovingRMS('WindowLength',app.windowLength,'OverlapLength',app.overlap);

            %% MVC Normalisation
            app.NormalizedData = MVC(app.no_outliers,app.path,app.file);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            event = events('pass');
            
            % set default visibility of plots
            app.PlotPanel.Visible = 'on';
            app.PlotPanel_2.Visible = 'off';
            app.PlotPanel_3.Visible = 'off';
            app.PlotPanel_4.Visible = 'off';
            app.PlotPanel_5.Visible = 'off';
            app.PlotPanel_6.Visible = 'off';
            app.PlotPanel_7.Visible = 'off';
            app.DonePanel.Visible = 'off';
            app.OutlierremovalsettingsPanel.Visible = 'off';
            app.BandpassfiltersettingsPanel.Visible = 'off';
            app.SavitzkyGolayfiltersettingsPanel.Visible = 'off';
            app.MovingRMSsettingsPanel.Visible = 'off';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {210, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Selection changed function: ChoosemuscleButtonGroup
        function ChoosemuscleButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChoosemuscleButtonGroup.SelectedObject;
            switch selectedButton
                case app.ECR_rightButton
                    plotFunction(app, 5);
                case app.delt_leftButton
                    plotFunction(app, 3);                  
                case app.delt_rightButton
                    plotFunction(app, 4);
                case app.trapezius_leftButton
                    plotFunction(app, 1);
                case app.trapezius_rightButton
                    plotFunction(app, 2);
                otherwise
            end
        end

        % Selection changed function: DataofButtonGroup
        function DataofButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataofButtonGroup.SelectedObject;
            if (selectedButton == app.selectedmuscleButton)
                app.ChoosemuscleButtonGroup.Visible = 'on';
                ChoosemuscleButtonGroupSelectionChanged(app, event)
            elseif (selectedButton == app.allmusclesButton)
                app.ChoosemuscleButtonGroup.Visible = 'off';
                plotAll(app);
            end
        end

        % Button pushed function: ReadfileButton
        function ReadfileButtonPushed(app, event)
            %% Load raw data
            [app.file,app.path] = uigetfile('*.txt');
            figure(app.UIFigure);
            fullPath = append(app.path, app.file);
            [app.header, app.data] = ReadFile(fullPath);
            app.samplingRate = app.header.samplingRate;
            
            update(app);
            plotFunction(app, 1);
        end

        % Selection changed function: PlottheButtonGroup
        function PlottheButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlottheButtonGroup.SelectedObject;
            app.PlotPanel.Visible = 'off';
            app.PlotPanel_2.Visible = 'off';
            app.PlotPanel_3.Visible = 'off';
            app.PlotPanel_4.Visible = 'off';
            app.PlotPanel_5.Visible = 'off';
            app.PlotPanel_6.Visible = 'off';
            app.PlotPanel_7.Visible = 'off';
            app.DonePanel.Visible = 'off';
            app.OutlierremovalsettingsPanel.Visible = 'off';
            app.BandpassfiltersettingsPanel.Visible = 'off';
            app.SavitzkyGolayfiltersettingsPanel.Visible = 'off';
            app.MovingRMSsettingsPanel.Visible = 'off';
            
            if (selectedButton == app.datainmVButton)
                app.PlotPanel.Visible = 'on';
            elseif (selectedButton == app.fouriertransformButton)
                app.PlotPanel_2.Visible = 'on';
            elseif (selectedButton == app.datawithoutoutliersButton)
                app.PlotPanel_3.Visible = 'on';
                app.OutlierremovalsettingsPanel.Visible = 'on';
                app.DonePanel.Visible = 'on';
            elseif (selectedButton == app.databandpassfilteredButton)
                app.PlotPanel_4.Visible = 'on';
                app.BandpassfiltersettingsPanel.Visible = 'on';
                app.DonePanel.Visible = 'on';
            elseif (selectedButton == app.dataSavitzkyGolayfilteredButton)
                app.PlotPanel_5.Visible = 'on';
                app.SavitzkyGolayfiltersettingsPanel.Visible = 'on';
                app.DonePanel.Visible = 'on';
            elseif (selectedButton == app.movingrmsofthedataButton)
                app.PlotPanel_6.Visible = 'on';
                app.MovingRMSsettingsPanel.Visible = 'on';
                app.DonePanel.Visible = 'on';
            elseif (selectedButton == app.normalizeddataButton)
                app.PlotPanel_7.Visible = 'on';
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            update(app);
            DataofButtonGroupSelectionChanged(app, event);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            %% Data export
            if (app.data ~= [0,0,0,0,0])
                fileId = fopen('settings.txt','w');
                fprintf(fileId,"Date: %s  & Time: %s \n",app.header.date, app.header.time);
                fprintf(fileId,"Sampling frequency: %d  & Resolution: %d \n", app.header.samplingRate, app.header.resolution(1));
                fprintf(fileId,"Settings used: \n StandardDeviation to remove outliers: %d, windowSize for replacing outliers: %d\n",app.deviationNumber, app.windowSize);
                fprintf(fileId,"Process track A:\n");
                fprintf(fileId,"    Bandpassfilter: cuttoffrequencies: %d %d\n", app.lowCutoffFreq, app.highCutoffFreq);
                fprintf(fileId,"    Savitzky-Golay filtering: order and framelength: %d %d\n", app.order, app.frameLength);
                fprintf(fileId,"Process track B:\n");
                fprintf(fileId,"    Windowlength, %d", app.windowLength);
                fprintf(fileId,"    Overlap, %d",app.overlap);
                
                writematrix(app.no_outliers,'Processed Data before normalization.csv','Delimiter',';')
                writematrix(app.NormalizedData,'Processed Data after normalization.csv','Delimiter',';')
            end
        end

        % Value changed function: WindowsizeSlider
        function WindowsizeSliderValueChanged(app, event)
            value = app.WindowsizeSlider.Value;
            app.windowSize = round(value);
        end

        % Value changed function: DeviationnumberSlider
        function DeviationnumberSliderValueChanged(app, event)
            value = app.DeviationnumberSlider.Value;
            app.deviationNumber = round(value);
        end

        % Value changed function: WindowlengthSlider
        function WindowlengthSliderValueChanged(app, event)
            value = app.WindowlengthSlider.Value;
            value = round(value);

            if (app.overlap >= value)
                uialert(app.UIFigure, "Overlap length must be less than the window length!", "Warning")
            else
                app.windowLength = value;
            end            
        end

        % Value changed function: OverlapSlider
        function OverlapSliderValueChanged(app, event)
            value = app.OverlapSlider.Value;
            value = round(value);

            if (app.windowLength <= value)
                uialert(app.UIFigure, "Overlap length must be less than the window length!", "Warning")
            else
                app.overlap = value;
            end
        end

        % Value changed function: FramelengthSlider
        function FramelengthSliderValueChanged(app, event)
            value = app.FramelengthSlider.Value;
            value = round(value);
            if (mod(value,2) == 0) % even number
                value = value+1;
            end
            app.frameLength = value;
        end

        % Value changed function: OrderSlider
        function OrderSliderValueChanged(app, event)
            value = app.OrderSlider.Value;
            app.order = round(value);
        end

        % Value changed function: HighcutofffrequencySlider
        function HighcutofffrequencySliderValueChanged(app, event)
            value = app.HighcutofffrequencySlider.Value;
            value = round(value);
            
            if (app.lowCutoffFreq >= value)
                uialert(app.UIFigure, "Low cutoff frequency is higher than the high cutoff frequency!", "Warning")
            else
                app.highCutoffFreq = value;
            end
        end

        % Value changed function: LowcutofffrequencySlider
        function LowcutofffrequencySliderValueChanged(app, event)
            value = app.LowcutofffrequencySlider.Value;
            value = round(value);

            if (app.highCutoffFreq <= value)
                uialert(app.UIFigure, "High cutoff frequency is lower than the low cutoff frequency!", "Warning")
            else
                app.lowCutoffFreq = value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SettingsPanel = uipanel(app.LeftPanel);
            app.SettingsPanel.Title = 'Settings';
            app.ChoosemuscleButtonGroup = uibuttongroup(app.SettingsPanel);
            app.ChoosemuscleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoosemuscleButtonGroupSelectionChanged, true);
            app.ChoosemuscleButtonGroup.Title = 'Choose muscle';
            app.ECR_rightButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.ECR_rightButton.Text = 'ECR_right';
            app.delt_leftButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.delt_leftButton.Text = 'delt_left';
            app.delt_rightButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.delt_rightButton.Text = 'delt_right';
            app.trapezius_leftButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.trapezius_leftButton.Text = 'trapezius_left';
            app.trapezius_leftButton.Value = true;
            app.trapezius_rightButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.trapezius_rightButton.Text = 'trapezius_right';
            app.DataofButtonGroup = uibuttongroup(app.SettingsPanel);
            app.DataofButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataofButtonGroupSelectionChanged, true);
            app.DataofButtonGroup.Title = 'Data of';
            app.selectedmuscleButton = uiradiobutton(app.DataofButtonGroup);
            app.selectedmuscleButton.Text = 'selected muscle';
            app.selectedmuscleButton.Value = true;
            app.allmusclesButton = uiradiobutton(app.DataofButtonGroup);
            app.allmusclesButton.Text = 'all muscles';
            app.PlottheButtonGroup = uibuttongroup(app.SettingsPanel);
            app.PlottheButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlottheButtonGroupSelectionChanged, true);
            app.PlottheButtonGroup.Title = 'Plot the';
            app.datainmVButton = uiradiobutton(app.PlottheButtonGroup);
            app.datainmVButton.Text = 'data in mV';
            app.datainmVButton.Value = true;
            app.fouriertransformButton = uiradiobutton(app.PlottheButtonGroup);
            app.fouriertransformButton.Text = 'fourier transform';
            app.datawithoutoutliersButton = uiradiobutton(app.PlottheButtonGroup);
            app.datawithoutoutliersButton.Text = 'data without outliers';
            app.databandpassfilteredButton = uiradiobutton(app.PlottheButtonGroup);
            app.databandpassfilteredButton.Text = 'data bandpass filtered';
            app.dataSavitzkyGolayfilteredButton = uiradiobutton(app.PlottheButtonGroup);
            app.dataSavitzkyGolayfilteredButton.Text = 'data Savitzky-Golay filtered';
            app.movingrmsofthedataButton = uiradiobutton(app.PlottheButtonGroup);
            app.movingrmsofthedataButton.Text = 'moving rms of the data';
            app.normalizeddataButton = uiradiobutton(app.PlottheButtonGroup);
            app.normalizeddataButton.Text = 'normalized data';
            app.ReadfileButton = uibutton(app.LeftPanel, 'push');
            app.ReadfileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadfileButtonPushed, true);
            app.ReadfileButton.Text = 'Read file';
            app.RightPanel = uipanel(app.GridLayout);
            app.sEMGLabel = uilabel(app.RightPanel);
            app.sEMGLabel.Text = 'sEMG';
            app.PlotPanel = uipanel(app.RightPanel);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Raw data in mV')
            xlabel(app.UIAxes, 'time (milliseconds)')
            ylabel(app.UIAxes, 'Voltage (mV)')
            app.PlotPanel_2 = uipanel(app.RightPanel);
            app.PlotPanel_2.Title = 'Plot';
            app.UIAxes2 = uiaxes(app.PlotPanel_2);
            title(app.UIAxes2, 'FFT')
            xlabel(app.UIAxes2, 'frequency (Hz)')
            ylabel(app.UIAxes2, 'Amplitude (mV)')
            app.PlotPanel_3 = uipanel(app.RightPanel);
            app.PlotPanel_3.Title = 'Plot';
            app.UIAxes3 = uiaxes(app.PlotPanel_3);
            title(app.UIAxes3, 'Without Outliers')
            xlabel(app.UIAxes3, 'time (milliseconds)')
            ylabel(app.UIAxes3, 'Voltage (mV)')
            app.PlotPanel_4 = uipanel(app.RightPanel);
            app.PlotPanel_4.Title = 'Plot';
            app.UIAxes4 = uiaxes(app.PlotPanel_4);
            title(app.UIAxes4, 'with bandpass filter')
            xlabel(app.UIAxes4, 'time (milliseconds)')
            ylabel(app.UIAxes4, 'Voltage (mV)')
            app.PlotPanel_5 = uipanel(app.RightPanel);
            app.PlotPanel_5.Title = 'Plot';
            app.UIAxes5 = uiaxes(app.PlotPanel_5);
            title(app.UIAxes5, 'with Savitzky-Golay filter')
            xlabel(app.UIAxes5, 'time (milliseconds)')
            ylabel(app.UIAxes5, 'Voltage (mV)')
            app.PlotPanel_6 = uipanel(app.RightPanel);
            app.PlotPanel_6.Title = 'Plot';
            app.UIAxes6 = uiaxes(app.PlotPanel_6);
            title(app.UIAxes6, 'moving rms')
            xlabel(app.UIAxes6, 'time (milliseconds)')
            ylabel(app.UIAxes6, 'Voltage (mV)')
            app.PlotPanel_7 = uipanel(app.RightPanel);
            app.PlotPanel_7.Title = 'Plot';
            app.UIAxes7 = uiaxes(app.PlotPanel_7);
            title(app.UIAxes7, 'Normalized')
            xlabel(app.UIAxes7, 'time (milliseconds)')
            ylabel(app.UIAxes7, 'Voltage (mV)')
            app.DonePanel = uipanel(app.RightPanel);
            app.DoneButton = uibutton(app.DonePanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ExportButton = uibutton(app.RightPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.OutlierremovalsettingsPanel = uipanel(app.RightPanel);
            app.OutlierremovalsettingsPanel.Title = 'Outlier removal settings';
            app.WindowsizeSliderLabel = uilabel(app.OutlierremovalsettingsPanel);
            app.WindowsizeSliderLabel.Text = 'Window size';
            app.WindowsizeSlider = uislider(app.OutlierremovalsettingsPanel);
            app.WindowsizeSlider.Limits = [1 80];
            app.WindowsizeSlider.ValueChangedFcn = createCallbackFcn(app, @WindowsizeSliderValueChanged, true);
            app.WindowsizeSlider.Value = 20;
            app.DeviationnumberSliderLabel = uilabel(app.OutlierremovalsettingsPanel);
            app.DeviationnumberSliderLabel.Text = 'Deviation number';
            app.DeviationnumberSlider = uislider(app.OutlierremovalsettingsPanel);
            app.DeviationnumberSlider.Limits = [1 8];
            app.DeviationnumberSlider.MajorTicks = [1 2 4 6 8];
            app.DeviationnumberSlider.ValueChangedFcn = createCallbackFcn(app, @DeviationnumberSliderValueChanged, true);
            app.DeviationnumberSlider.MinorTicks = [3 5 7];
            app.DeviationnumberSlider.Value = 2;
            app.MovingRMSsettingsPanel = uipanel(app.RightPanel);
            app.MovingRMSsettingsPanel.Title = 'Moving RMS settings';
            app.WindowlengthSliderLabel = uilabel(app.MovingRMSsettingsPanel);
            app.WindowlengthSliderLabel.Text = 'Window length';
            app.WindowlengthSlider = uislider(app.MovingRMSsettingsPanel);
            app.WindowlengthSlider.Limits = [1 80];
            app.WindowlengthSlider.MajorTicks = [1 10 20 30 40 50 60 70 80];
            app.WindowlengthSlider.ValueChangedFcn = createCallbackFcn(app, @WindowlengthSliderValueChanged, true);
            app.WindowlengthSlider.MinorTicks = [2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80];
            app.WindowlengthSlider.Value = 20;
            app.OverlapSliderLabel = uilabel(app.MovingRMSsettingsPanel);
            app.OverlapSliderLabel.Text = 'Overlap';
            app.OverlapSlider = uislider(app.MovingRMSsettingsPanel);
            app.OverlapSlider.Limits = [0 50];
            app.OverlapSlider.MajorTicks = [0 10 20 30 40 50];
            app.OverlapSlider.ValueChangedFcn = createCallbackFcn(app, @OverlapSliderValueChanged, true);
            app.OverlapSlider.MinorTicks = [2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80];
            app.OverlapSlider.Value = 19;
            app.SavitzkyGolayfiltersettingsPanel = uipanel(app.RightPanel);
            app.SavitzkyGolayfiltersettingsPanel.Title = 'Savitzky-Golay filter settings';
            app.FramelengthSliderLabel = uilabel(app.SavitzkyGolayfiltersettingsPanel);
            app.FramelengthSliderLabel.Text = 'Frame length';
            app.FramelengthSlider = uislider(app.SavitzkyGolayfiltersettingsPanel);
            app.FramelengthSlider.Limits = [1 30];
            app.FramelengthSlider.ValueChangedFcn = createCallbackFcn(app, @FramelengthSliderValueChanged, true);
            app.FramelengthSlider.Value = 15;
            app.OrderSliderLabel = uilabel(app.SavitzkyGolayfiltersettingsPanel);
            app.OrderSliderLabel.Text = 'Order';
            app.OrderSlider = uislider(app.SavitzkyGolayfiltersettingsPanel);
            app.OrderSlider.Limits = [1 6];
            app.OrderSlider.MajorTicks = [1 10 20 30];
            app.OrderSlider.ValueChangedFcn = createCallbackFcn(app, @OrderSliderValueChanged, true);
            app.OrderSlider.MinorTicks = [2 4 6 8 12 14 16 18 22 24 26 28];
            app.OrderSlider.Value = 2;
            app.BandpassfiltersettingsPanel = uipanel(app.RightPanel);
            app.BandpassfiltersettingsPanel.Title = 'Bandpass filter settings';
            app.LowcutofffrequencySliderLabel = uilabel(app.BandpassfiltersettingsPanel);
            app.LowcutofffrequencySliderLabel.Text = 'Low cutoff frequency';
            app.LowcutofffrequencySlider = uislider(app.BandpassfiltersettingsPanel);
            app.LowcutofffrequencySlider.Limits = [1 300];
            app.LowcutofffrequencySlider.MajorTicks = [1 50 100 150 200 250 300];
            app.LowcutofffrequencySlider.ValueChangedFcn = createCallbackFcn(app, @LowcutofffrequencySliderValueChanged, true);
            app.LowcutofffrequencySlider.MinorTicks = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250 260 270 280 290 300];
            app.LowcutofffrequencySlider.Value = 100;
            app.HighcutofffrequencySliderLabel = uilabel(app.BandpassfiltersettingsPanel);
            app.HighcutofffrequencySliderLabel.Text = 'High cutoff frequency';
            app.HighcutofffrequencySlider = uislider(app.BandpassfiltersettingsPanel);
            app.HighcutofffrequencySlider.Limits = [1 600];
            app.HighcutofffrequencySlider.MajorTicks = [1 100 200 300 400 500 600];
            app.HighcutofffrequencySlider.ValueChangedFcn = createCallbackFcn(app, @HighcutofffrequencySliderValueChanged, true);
            app.HighcutofffrequencySlider.MinorTicks = [20 40 60 80 100 120 140 160 180 200 220 240 260 280 300 320 340 360 380 400 420 440 460 480 500 520 540 560 580 600];
            app.HighcutofffrequencySlider.Value = 300;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sEMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end