classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        sEMGLabel                  matlab.ui.control.Label
        SettingsPanel              matlab.ui.container.Panel
        ChoosemuscleButtonGroup    matlab.ui.container.ButtonGroup
        ECR_rightButton            matlab.ui.control.RadioButton
        delt_leftButton            matlab.ui.control.RadioButton
        delt_rightButton           matlab.ui.control.RadioButton
        trapezius_leftButton       matlab.ui.control.RadioButton
        trapezius_rightButton      matlab.ui.control.RadioButton
        DataofButtonGroup          matlab.ui.container.ButtonGroup
        selectedmuscleButton       matlab.ui.control.RadioButton
        allmusclesButton           matlab.ui.control.RadioButton
        PlottheButtonGroup         matlab.ui.container.ButtonGroup
        rawdataButton              matlab.ui.control.RadioButton
        fouriertransformButton     matlab.ui.control.RadioButton
        datawithoutoutliersButton  matlab.ui.control.RadioButton
        ReadfileButton             matlab.ui.control.Button
        PlotPanel                  matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        header = []
        data = [0,0,0,0,0]
        fourier = [0,0,0,0,0]
    end
    methods (Access = public)
%         function [header, M] = ReadFile(file, path)
%             %[file,path] = uigetfile('*.txt')
%             %read header
%             COMMANDS = regexp(fileread(path + file), '#', 'split');
%             if isempty(COMMANDS{end}); COMMANDS(end) = []; end  %end of file correction
%             DATA = char(COMMANDS(3));
%             A = jsondecode(DATA);
%             Header = A.x00_07_80_3B_46_63;
%             
%             %read raw data
%             M = readmatrix(path + file,'Range',4);
%             M(:,8)=[]
%             M(:,1:2)=[];
%         end
%         
%         function data = readFile(path)
%           
%             
%             fileID = fopen(path)
%             D = textscan(fileID)
%             %[header, M] = ReadFile(path +file);
%             
%             %M = readmatrix(file,'Range',4);
%             data = D;
%         end
               
        function plotFunction(app, column)
            plot(app.UIAxes, (1:length(app.data(:,column)))', app.data(:,column))
            legend(app.UIAxes, {'channel1'}, 'Location', 'Best')
            xlim(app.UIAxes, [0, length(app.data(:,column))]);
            
            plot(app.UIAxes2, (1:length(app.fourier(:,column)))', app.fourier(:,column))
            legend(app.UIAxes2, {'channel1'}, 'Location', 'Best')
            xlim(app.UIAxes2, [0, length(app.fourier(:,column))]);
            
%             plot(app.UIAxes, (1:length(data(:,2)))', data(:,2))
%             legend(app.UIAxes, {'channel2'}, 'Location', 'Best')
%             %app.UIAxes.YLim = [0 10000];
%             xlim(app.UIAxes, [0, length(data(:,2))]);
%             
%             plot(app.UIAxes, (1:length(data(:,3)))', data(:,3))
%             legend(app.UIAxes, {'channel3'}, 'Location', 'Best')
%             %app.UIAxes.YLim = [0 10000];
%             xlim(app.UIAxes, [0, length(data(:,3))]);
%             
%             plot(app.UIAxes, (1:length(data(:,4)))', data(:,4))
%             legend(app.UIAxes, {'channel4'}, 'Location', 'Best')
%             %app.UIAxes.YLim = [0 10000];
%             xlim(app.UIAxes, [0, length(data(:,4))]);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            event = events('pass');
            DataofButtonGroupSelectionChanged(app, event);
            ChoosemuscleButtonGroupSelectionChanged(app, event);             
        end

        % Selection changed function: ChoosemuscleButtonGroup
        function ChoosemuscleButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChoosemuscleButtonGroup.SelectedObject;
            switch selectedButton
                case app.ECR_rightButton
                    plotFunction(app, 5);
                    % changeSelection(app, 'C:\Users\maxim\OneDrive\Documenten\GitHub\DSP_sEMG\S1_MVC_ECR.txt')
                case app.delt_leftButton
                    plotFunction(app, 3);
                    %changeSelection(app, 'C:\Users\maxim\OneDrive\Documenten\GitHub\DSP_sEMG\S1_MVC_delt_links.txt')                    
                case app.delt_rightButton
                    plotFunction(app, 4);
                    %changeSelectiond(app, 'C:\Users\maxim\OneDrive\Documenten\GitHub\DSP_sEMG\S1_MVC_delt_rechts.txt')
                case app.trapezius_leftButton
                    plotFunction(app, 1);
                    %changeSelection(app, 'C:\Users\maxim\OneDrive\Documenten\GitHub\DSP_sEMG\S1_MVC_trapezius_links.txt')
                case app.trapezius_rightButton
                    plotFunction(app, 2);
                    %changeSelection(app, 'C:\Users\maxim\OneDrive\Documenten\GitHub\DSP_sEMG\S1_MVC_trapezius_rechts.txt')
                otherwise
            end
        end

        % Selection changed function: DataofButtonGroup
        function DataofButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataofButtonGroup.SelectedObject;
            if (selectedButton == app.selectedmuscleButton)
                app.ChoosemuscleButtonGroup.Visible = 'on';
                app.PlottheButtonGroup.Visible = 'on';
            elseif (selectedButton == app.allmusclesButton)
                app.ChoosemuscleButtonGroup.Visible = 'off';
                app.PlottheButtonGroup.Visible = 'off';
            end
        end

        % Button pushed function: ReadfileButton
        function ReadfileButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            fullPath = append(path, file);
            [app.header, app.data] = ReadFile(fullPath);
            
            M_mV = transformTo_mV(app.data, app.header.resolution);
            %fft
            app.fourier = fft(app.data,[],1);
            app.fourier(1,:)=0;
            
            %outlier removal
            numDerivation = 2;
            windowSize = 20;
            %no_outliers = FilterOutlierWindow(M_mV,numDerivation,windowSize);
            
            plotFunction(app, 1);
        end

        % Selection changed function: PlottheButtonGroup
        function PlottheButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlottheButtonGroup.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.sEMGLabel = uilabel(app.UIFigure);
            app.sEMGLabel.Text = 'sEMG';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.ChoosemuscleButtonGroup = uibuttongroup(app.SettingsPanel);
            app.ChoosemuscleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoosemuscleButtonGroupSelectionChanged, true);
            app.ChoosemuscleButtonGroup.Title = 'Choose muscle';
            app.ECR_rightButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.ECR_rightButton.Text = 'ECR_right';
            app.delt_leftButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.delt_leftButton.Text = 'delt_left';
            app.delt_rightButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.delt_rightButton.Text = 'delt_right';
            app.trapezius_leftButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.trapezius_leftButton.Text = 'trapezius_left';
            app.trapezius_leftButton.Value = true;
            app.trapezius_rightButton = uiradiobutton(app.ChoosemuscleButtonGroup);
            app.trapezius_rightButton.Text = 'trapezius_right';
            app.DataofButtonGroup = uibuttongroup(app.SettingsPanel);
            app.DataofButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataofButtonGroupSelectionChanged, true);
            app.DataofButtonGroup.Title = 'Data of';
            app.selectedmuscleButton = uiradiobutton(app.DataofButtonGroup);
            app.selectedmuscleButton.Text = 'selected muscle';
            app.selectedmuscleButton.Value = true;
            app.allmusclesButton = uiradiobutton(app.DataofButtonGroup);
            app.allmusclesButton.Text = 'all muscles';
            app.PlottheButtonGroup = uibuttongroup(app.SettingsPanel);
            app.PlottheButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlottheButtonGroupSelectionChanged, true);
            app.PlottheButtonGroup.Title = 'Plot the';
            app.rawdataButton = uiradiobutton(app.PlottheButtonGroup);
            app.rawdataButton.Text = 'raw data';
            app.rawdataButton.Value = true;
            app.fouriertransformButton = uiradiobutton(app.PlottheButtonGroup);
            app.fouriertransformButton.Text = 'fourier transform';
            app.datawithoutoutliersButton = uiradiobutton(app.PlottheButtonGroup);
            app.datawithoutoutliersButton.Text = 'data without outliers';
            app.ReadfileButton = uibutton(app.UIFigure, 'push');
            app.ReadfileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadfileButtonPushed, true);
            app.ReadfileButton.Text = 'Read file';
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Raw data (mV)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = 1;
            app.UIAxes.XTickLabel = {'0'; '5'; '10'; '15'; '20'};
            app.UIAxes.YTick = 1000;
            app.UIAxes.YTickLabel = {'0'; '1000'; '2000'; '3000'; '4000'; '5000'};
            app.UIAxes2 = uiaxes(app.PlotPanel);
            title(app.UIAxes2, 'FFT')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Without Outliers')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end